classdef ControllerTuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BupRegulatorTunerUIFigure      matlab.ui.Figure
        TouchdownpointPanel            matlab.ui.container.Panel
        LatitudedegLabel               matlab.ui.control.Label
        TDP_lat_edit                   matlab.ui.control.NumericEditField
        LongitudedegLabel              matlab.ui.control.Label
        TDP_lon_edit                   matlab.ui.control.NumericEditField
        AltitudemLabel                 matlab.ui.control.Label
        TDP_alt_edit                   matlab.ui.control.NumericEditField
        InitialpointPanel              matlab.ui.container.Panel
        LatitudedegLabel_2             matlab.ui.control.Label
        Initial_lat_edit               matlab.ui.control.NumericEditField
        LongitudedegLabel_2            matlab.ui.control.Label
        Initial_lon_edit               matlab.ui.control.NumericEditField
        AltitudemLabel_2               matlab.ui.control.Label
        Initial_alt_edit               matlab.ui.control.NumericEditField
        WindcontrolPanel               matlab.ui.container.Panel
        WindAngleKnobLabel             matlab.ui.control.Label
        WindAngleKnob                  matlab.ui.control.Knob
        SpeedSliderLabel               matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
        AutoWind                       matlab.ui.control.CheckBox
        Base_Reg_ParamPanel            matlab.ui.container.Panel
        StapLimitLabel                 matlab.ui.control.Label
        Base_RegStrapLimit             matlab.ui.control.NumericEditField
        GainSensitivityLabel           matlab.ui.control.Label
        Base_GainSens                  matlab.ui.control.NumericEditField
        BoxSizemLabel                  matlab.ui.control.Label
        BoxSize                        matlab.ui.control.NumericEditField
        AngleStepdegLabel              matlab.ui.control.Label
        AngleManevr                    matlab.ui.control.NumericEditField
        ArrivalRadiusmLabel            matlab.ui.control.Label
        ArrivalRadius                  matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        dTab                           matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        DTab                           matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        PlantParametersPanel           matlab.ui.container.Panel
        HorspeedVhmsEditFieldLabel     matlab.ui.control.Label
        Vh_Edit                        matlab.ui.control.NumericEditField
        VerspeedVvmsEditFieldLabel     matlab.ui.control.Label
        Vv_Edit                        matlab.ui.control.NumericEditField
        Turnspeedat50degsLabel         matlab.ui.control.Label
        TurnSpeed_Edit                 matlab.ui.control.NumericEditField
        LoadLastParametersButton       matlab.ui.control.Button
        Reg_SwitchPanel                matlab.ui.container.Panel
        TouchdownCommandSwitchLabel    matlab.ui.control.Label
        TD_CMD_Switch                  matlab.ui.control.Switch
        TouchdownRegulatorSwitchLabel  matlab.ui.control.Label
        TD_Reg_Select                  matlab.ui.control.Switch
        InternalcoursecalculatorSwitchLabel  matlab.ui.control.Label
        Int_Course_Calc                matlab.ui.control.Switch
        RegulatorMode                  matlab.ui.container.ButtonGroup
        OnlyTDPButton                  matlab.ui.control.RadioButton
        BoxButton                      matlab.ui.control.RadioButton
        AngleBoxButton                 matlab.ui.control.RadioButton
        ReliefSettings                 matlab.ui.container.Panel
        AutoRelief                     matlab.ui.control.CheckBox
        CurrentpointreliefmLabel       matlab.ui.control.Label
        ConstRelief_EditField          matlab.ui.control.NumericEditField
        TouchdownpointreliefmLabel     matlab.ui.control.Label
        ConstTDPRelief_EditField       matlab.ui.control.NumericEditField
        ConstAvailable                 matlab.ui.control.CheckBox
        RadiopointreliefmEditFieldLabel  matlab.ui.control.Label
        RadioPointReliefEdit           matlab.ui.control.NumericEditField
        radioPointPanel                matlab.ui.container.Panel
        LatitudedegLabel_3             matlab.ui.control.Label
        radioPointLatitudeEdit         matlab.ui.control.NumericEditField
        LongitudedegLabel_3            matlab.ui.control.Label
        radioPointLongitudeEdit        matlab.ui.control.NumericEditField
        AltitudemLabel_3               matlab.ui.control.Label
        radioPointAltitudeEdit         matlab.ui.control.NumericEditField
        SendtimesecLabel               matlab.ui.control.Label
        sendtimeEdit                   matlab.ui.control.EditField
    end


    properties (Access = public)
        TDP_LAT
        TDP_LON
        TDP_ALT
        Init_LAT
        Init_LON
        Init_ALT
        time
        vec_state
        output
    end

    properties (Access = private)
        X_TDP
        Y_TDP
    end

    methods (Access = private)
    
        function EnableDisableWindPanel(app, value)
            if value == 0
                app.WindAngleKnob.Enable = 'on';
                app.SpeedSlider.Enable = 'on';
                set_param('fullSystemModel/WindScenario/AutoModeA', 'sw',  '0');  
                set_param('fullSystemModel/WindScenario/AutoModeS', 'sw',  '0');   
            else
                app.WindAngleKnob.Enable = 'off';
                app.SpeedSlider.Enable = 'off';
                set_param('fullSystemModel/WindScenario/AutoModeA', 'sw',  '1');  
                set_param('fullSystemModel/WindScenario/AutoModeS', 'sw',  '1');  
            end
        end
        function EnableDisableReliefPanel(app, value)
            if value == 0
                app.ConstRelief_EditField.Enable  = 'on';
                app.ConstAvailable.Enable  = 'on';
                app.ConstTDPRelief_EditField.Enable = 'on';
                set_param('fullSystemModel/ReliefScenario/AutoModeA', 'sw',  '0');  
                set_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw',  '0');   
            else
                app.ConstRelief_EditField.Enable  = 'off';
                app.ConstAvailable.Enable  = 'off';
                app.ConstTDPRelief_EditField.Enable = 'off';
                set_param('fullSystemModel/ReliefScenario/AutoModeA', 'sw',  '1');  
                set_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw',  '1');  
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: TDP_lat_edit
        function TDP_lat_editValueChanged(app, event)
            app.TDP_LAT = app.TDP_lat_edit.Value;
            
        end

        % Value changed function: TDP_lon_edit
        function TDP_lon_editValueChanged(app, event)
            app.TDP_LON = app.TDP_lon_edit.Value;
            
        end

        % Value changed function: Initial_lat_edit
        function Initial_lat_editValueChanged(app, event)
            app.Init_LAT = app.Initial_lat_edit.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Очищаем графики от старых данных
            cla(app.UIAxes, 'reset')
            cla(app.UIAxes2,'reset')
            
            % Устанавливаем координаты точки приземления и точки выброски
            app.TDP_LAT = app.TDP_lat_edit.Value;
            app.TDP_LON = app.TDP_lon_edit.Value;
            app.TDP_ALT = app.TDP_alt_edit.Value;
            app.Init_LAT = app.Initial_lat_edit.Value;
            app.Init_LON = app.Initial_lon_edit.Value;
            app.Init_ALT = app.Initial_alt_edit.Value;
            set_param('fullSystemModel/TDP_lon_x', 'Value',  num2str(app.TDP_LON,10));
            set_param('fullSystemModel/TDP_lat_y', 'Value', num2str(app.TDP_LAT,10));
            set_param('fullSystemModel/TDP_alt_z', 'Value', num2str(app.TDP_ALT,10));
            set_param('fullSystemModel/Initial_LON', 'Value', num2str(app.Init_LON,10));
            set_param('fullSystemModel/Initial_LAT', 'Value', num2str(app.Init_LAT,10));
            set_param('fullSystemModel/Initial_ALT', 'Value', num2str(app.Init_ALT,10));
            
            % Устанавливаем точку приземления от радиостанции
            set_param('fullSystemModel/Radiostation/radioPoint.lat', 'Value', num2str(app.radioPointLatitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/radioPoint.lon', 'Value', num2str(app.radioPointLongitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/radioPoint.alt', 'Value', num2str(app.radioPointAltitudeEdit.Value));
            set_param('fullSystemModel/Radiostation/sendTime', 'Time', num2str(app.sendtimeEdit.Value)); 
            
            % Настроим параметры объекта управления
            set_param('fullSystemModel/Plant/Vh1', 'Gain',  num2str(app.Vh_Edit.Value,10));
            set_param('fullSystemModel/Plant/Vh2', 'Gain',  num2str(app.Vh_Edit.Value,10));
            set_param('fullSystemModel/Plant/Vv', 'Gain',  num2str(app.Vv_Edit.Value,10));
            set_param('fullSystemModel/Plant/AngleSpeed', 'Gain',  num2str(1/(50/app.TurnSpeed_Edit.Value),10));
            
            % Настроим параметры базового регулятора
            set_param('fullSystemModel/flightController/Targeting/Base_Sat', 'UpperLimit', num2str(app.Base_RegStrapLimit.Value,10)); 
            set_param('fullSystemModel/flightController/Targeting/Base_Sat', 'LowerLimit', num2str(-app.Base_RegStrapLimit.Value,10)); % со знаком минус 
            set_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain', num2str(app.Base_GainSens.Value,10));
            set_param('fullSystemModel/flightController/BoxSize', 'Value', num2str(app.BoxSize.Value,10));
            set_param('fullSystemModel/flightController/AngleManevr', 'Value', num2str(app.AngleManevr.Value,10));
            set_param('fullSystemModel/flightController/ArrivalRadius', 'Value', num2str(app.ArrivalRadius.Value,10));
            set_param('fullSystemModel/flightController/TurnSpeed', 'Value', num2str(app.TurnSpeed_Edit.Value,10)); 
                      
            % Зададаём параметры ветра
            set_param('fullSystemModel/WindScenario/WindSpeed', 'Value',  num2str(app.SpeedSlider.Value));  
            set_param('fullSystemModel/WindScenario/WindAngle', 'Value',  num2str(app.WindAngleKnob.Value));
           
            % Устанавливаем ключи
            set_param('fullSystemModel/flightController/TD_SysSwitch', 'sw', app.TD_CMD_Switch.Value);
            set_param('fullSystemModel/flightController/DataAnalyzer/CourseSwitch', 'sw', app.Int_Course_Calc.Value);
            %set_param('fullSystemModel/flightController/Mode','Value', app.TD_Reg_Select.Value);
            
            
            % Устанавливаем параметры имитатора рельефа
            set_param('fullSystemModel/ReliefScenario/ReliefValue', 'Value',           num2str(app.ConstRelief_EditField.Value));  
            set_param('fullSystemModel/ReliefScenario/ReliefAvailableValue', 'Value',  num2str(app.ConstAvailable.Value));
            set_param('fullSystemModel/ReliefScenario/ReliefOnTDPValue', 'Value',      num2str(app.ConstTDPRelief_EditField.Value));  
            set_param('fullSystemModel/ReliefScenario/ReliefOnRPValue', 'Value',       num2str(app.RadioPointReliefEdit.Value));  
            
            % Устанавливаем режим регулятора
            if app.OnlyTDPButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '0');
            elseif app.AngleBoxButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '2');
            elseif app.BoxButton.Value == 1
                set_param('fullSystemModel/flightController/Mode','Value', '1');
            end

            [app.time, app.vec_state, app.output] = sim('fullSystemModel',3000);
            % Обрезаем вектор до 300 метров по высоте
            for i = 1:length(app.output(:,3))
                %if app.output(i,3) - str2double(get_param('BUP_FOR_GUI/ReliefScenario/ReliefOnTDPValue', 'Value')) <= app.TDP_alt_edit.Value
                if app.output(i,3) <= 0
                    break;
                end
            end
            app.output = app.output(1:i,:);
            % Обозначим точку необходимого приземления
            app.X_TDP = [app.TDP_LON - 0.0027, app.TDP_LON + 0.0027]; % 0.01
            app.Y_TDP = [app.TDP_LAT - 0.0027, app.TDP_LAT + 0.0027]; % 0.01
            % Строим траекторию
            plot(app.UIAxes, app.output(:,1), app.output(:,2))
            % И добавим точку необходимого пересечения
            hold(app.UIAxes,'on')
            grid(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            plot(app.UIAxes, app.X_TDP, [app.TDP_LAT, app.TDP_LAT], 'k')
            plot(app.UIAxes, [app.TDP_LON, app.TDP_LON], app.Y_TDP, 'k')
            
            %% Окружности КВО
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.UIAxes, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.UIAxes, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.UIAxes, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 450/1000*360/40000; % 450 м предел
            plot(app.UIAxes, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
      
            % Строим так же 3D график полета
            plot3(app.UIAxes2, app.output(:,1), app.output(:,2), app.output(:,3))
            hold(app.UIAxes2,'on')
            grid(app.UIAxes2,'on');
            plot3(app.UIAxes2, app.X_TDP, [app.TDP_LAT, app.TDP_LAT], [0, 0], 'k')
            plot3(app.UIAxes2, [app.TDP_LON, app.TDP_LON], app.Y_TDP,  [0, 0], 'k')
            rotate3d(app.UIAxes2, 'on'); 
            %% Окружности КВО
            R = 100/1000*360/40000; % 100 м КВО
            plot(app.UIAxes2, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 200/1000*360/40000; % 200 м КВО
            plot(app.UIAxes2, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 300/1000*360/40000; % 300 м КВО
            plot(app.UIAxes2, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            R = 450/1000*360/40000; % 450 м предел
            plot(app.UIAxes2, app.TDP_LON + R * cos(0:0.001:2*pi), app.TDP_LAT + R * sin(0:0.001:2*pi));
            
        end

        % Value changed function: TDP_alt_edit
        function TDP_alt_editValueChanged(app, event)
            app.TDP_ALT = app.TDP_alt_edit.Value;
            
        end

        % Value changed function: Initial_lon_edit
        function Initial_lon_editValueChanged(app, event)
            app.Init_LON = app.Initial_lon_edit.Value;
            
        end

        % Value changed function: Initial_alt_edit
        function Initial_alt_editValueChanged(app, event)
            app.Init_ALT = app.Initial_alt_edit.Value;
            
        end

        % Button pushed function: LoadLastParametersButton
        function LoadLastParametersButtonPushed(app, event)
            % Восстанавливаем точку приземления из Simulink
            app.TDP_lat_edit.Value = str2double( get_param('fullSystemModel/TDP_lat_y', 'Value'));
            app.TDP_lon_edit.Value = str2double( get_param('fullSystemModel/TDP_lon_x', 'Value'));
            app.TDP_alt_edit.Value = str2double( get_param('fullSystemModel/TDP_alt_z', 'Value'));
            
            % Восстанавливаем точку выброса из Simulink
            app.Initial_lat_edit.Value = str2double( get_param('fullSystemModel/Initial_LAT', 'Value'));
            app.Initial_lon_edit.Value = str2double( get_param('fullSystemModel/Initial_LON', 'Value'));
            app.Initial_alt_edit.Value = str2double( get_param('fullSystemModel/Initial_ALT', 'Value'));
            
            % Восстанавливаем точку приземления от радиостанции
            app.radioPointLatitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.lat', 'Value'));
            app.radioPointLongitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.lon', 'Value'));
            app.radioPointAltitudeEdit.Value = str2double( get_param('fullSystemModel/Radiostation/radioPoint.alt', 'Value'));
            app.sendtimeEdit.Value = ( get_param('fullSystemModel/Radiostation/sendTime', 'Time')); 
            
            % Восставливаем параметры объекта управления
            app.Vh_Edit.Value        = str2double( get_param('fullSystemModel/Plant/Vh1', 'Gain'));
            app.Vv_Edit.Value        = str2double( get_param('fullSystemModel/Plant/Vv', 'Gain'));
            app.TurnSpeed_Edit.Value = 50 /(1/(str2double( get_param('fullSystemModel/Plant/AngleSpeed', 'Gain'))));
            
            % Восстанавливаем параметры базового регулятора
            app.Base_RegStrapLimit.Value = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Sat', 'UpperLimit'));
            app.Base_GainSens.Value      = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain'));
            app.Base_GainSens.Value      = str2double( get_param('fullSystemModel/flightController/Targeting/Base_Gain', 'Gain'));
            app.BoxSize.Value            = str2double( get_param('fullSystemModel/flightController/BoxSize', 'Value'));
            app.AngleManevr.Value        = str2double( get_param('fullSystemModel/flightController/AngleManevr', 'Value'));
            app.ArrivalRadius.Value      = str2double( get_param('fullSystemModel/flightController/ArrivalRadius', 'Value'));
            
            % Восстанавливаем параметры переключателей
            app.TD_CMD_Switch.Value    = get_param('fullSystemModel/flightController/TD_SysSwitch', 'sw');
            app.Int_Course_Calc.Value  = get_param('fullSystemModel/flightController/DataAnalyzer/CourseSwitch', 'sw');
            %app.TD_Reg_Select.Value    = get_param('BUP_FOR_GUI/Easy_reg/Mode','Value');
            
            % Восстанавливаем параметры имитатора рельефа
            app.ConstRelief_EditField.Value    = str2double(get_param('fullSystemModel/ReliefScenario/ReliefValue', 'Value'));  
            app.ConstAvailable.Value           = str2double(get_param('fullSystemModel/ReliefScenario/ReliefAvailableValue', 'Value'));
            app.ConstTDPRelief_EditField.Value = str2double(get_param('fullSystemModel/ReliefScenario/ReliefOnTDPValue', 'Value'));
            app.AutoRelief.Value               = str2double(get_param('fullSystemModel/ReliefScenario/AutoModeR', 'sw'));
            app.RadioPointReliefEdit.Value    = str2double(get_param('fullSystemModel/ReliefScenario/ReliefOnRPValue', 'Value'));
            EnableDisableReliefPanel(app, app.AutoRelief.Value);


            % Восстанавливаем параметры имитатора ветра
            %set_param('BUP_FOR_GUI/WindSpeed', 'Value',  num2str(app.SpeedSlider.Value));  
            %set_param('BUP_FOR_GUI/WindAngle', 'Value',  num2str(app.WindAngleKnob.Value)); 
            app.AutoWind.Value  = str2double(get_param('fullSystemModel/WindScenario/AutoModeS', 'sw'));
            EnableDisableWindPanel(app, app.AutoWind.Value);
            
            % Восстанавливаем режим регулятора на радиокнопки
            temp = str2double(get_param('fullSystemModel/flightController/Mode','Value'));
            if temp == 0
                disp("= 0")
                app.OnlyTDPButton.Value = 1;
            elseif temp == 1
                disp("= 1")
                app.BoxButton.Value = 1;
            elseif temp == 2
                disp("= 2")
                 app.AngleBoxButton.Value = 1;
            end
   
            % Разблокируем кнопку запуска
            app.RunButton.Enable = 'on';
            
        end

        % Value changed function: TD_Reg_Select
        function TD_Reg_SelectValueChanged(app, event)
            if app.TD_Reg_Select.Value == '0'
                % Заблокируем поля посадочного регулятора
                app.BoxSize.Enable = 'off';
                app.AngleManevr.Enable = 'off';
                app.ArrivalRadius.Enable = 'off';
            else
                % Заблокируем поля посадочного регулятора
                % app.TD_Reg_ParamPanel.Visible = 'on';
                app.BoxSize.Enable = 'on';
                app.AngleManevr.Enable = 'on';
                app.ArrivalRadius.Enable = 'on';
            end
        end

        % Value changed function: AutoWind
        function AutoWindValueChanged(app, event)
            value = app.AutoWind.Value;
            EnableDisableWindPanel(app, value);
        end

        % Value changed function: AutoRelief
        function AutoReliefValueChanged(app, event)
            value = app.AutoRelief.Value;
            EnableDisableReliefPanel(app, value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BupRegulatorTunerUIFigure = uifigure;
            app.BupRegulatorTunerUIFigure.Name = 'Bup Regulator Tuner';
            app.TouchdownpointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.TouchdownpointPanel.Title = 'Touchdown point';
            app.LatitudedegLabel = uilabel(app.TouchdownpointPanel);
            app.LatitudedegLabel.Text = 'Latitude, deg';
            app.TDP_lat_edit = uieditfield(app.TouchdownpointPanel, 'numeric');
            app.TDP_lat_edit.ValueDisplayFormat = '%.6f';
            app.TDP_lat_edit.ValueChangedFcn = createCallbackFcn(app, @TDP_lat_editValueChanged, true);
            app.TDP_lat_edit.Value = 56.86825;
            app.LongitudedegLabel = uilabel(app.TouchdownpointPanel);
            app.LongitudedegLabel.Text = 'Longitude, deg';
            app.TDP_lon_edit = uieditfield(app.TouchdownpointPanel, 'numeric');
            app.TDP_lon_edit.ValueDisplayFormat = '%.6f';
            app.TDP_lon_edit.ValueChangedFcn = createCallbackFcn(app, @TDP_lon_editValueChanged, true);
            app.TDP_lon_edit.Value = 41.51329;
            app.AltitudemLabel = uilabel(app.TouchdownpointPanel);
            app.AltitudemLabel.Text = 'Altitude, m';
            app.TDP_alt_edit = uieditfield(app.TouchdownpointPanel, 'numeric');
            app.TDP_alt_edit.ValueDisplayFormat = '%.3f';
            app.TDP_alt_edit.ValueChangedFcn = createCallbackFcn(app, @TDP_alt_editValueChanged, true);
            app.InitialpointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.InitialpointPanel.Title = 'Initial point';
            app.LatitudedegLabel_2 = uilabel(app.InitialpointPanel);
            app.LatitudedegLabel_2.Text = 'Latitude, deg';
            app.Initial_lat_edit = uieditfield(app.InitialpointPanel, 'numeric');
            app.Initial_lat_edit.Limits = [-90 90];
            app.Initial_lat_edit.ValueDisplayFormat = '%.6f';
            app.Initial_lat_edit.ValueChangedFcn = createCallbackFcn(app, @Initial_lat_editValueChanged, true);
            app.Initial_lat_edit.Value = 56.86825;
            app.LongitudedegLabel_2 = uilabel(app.InitialpointPanel);
            app.LongitudedegLabel_2.Text = 'Longitude, deg';
            app.Initial_lon_edit = uieditfield(app.InitialpointPanel, 'numeric');
            app.Initial_lon_edit.ValueDisplayFormat = '%.6f';
            app.Initial_lon_edit.ValueChangedFcn = createCallbackFcn(app, @Initial_lon_editValueChanged, true);
            app.Initial_lon_edit.Value = 41.48775;
            app.AltitudemLabel_2 = uilabel(app.InitialpointPanel);
            app.AltitudemLabel_2.Text = 'Altitude, m';
            app.Initial_alt_edit = uieditfield(app.InitialpointPanel, 'numeric');
            app.Initial_alt_edit.ValueDisplayFormat = '%.3f';
            app.Initial_alt_edit.ValueChangedFcn = createCallbackFcn(app, @Initial_alt_editValueChanged, true);
            app.Initial_alt_edit.Value = 16000;
            app.WindcontrolPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.WindcontrolPanel.Title = 'Wind control';
            app.WindAngleKnobLabel = uilabel(app.WindcontrolPanel);
            app.WindAngleKnobLabel.Text = 'Wind Angle';
            app.WindAngleKnob = uiknob(app.WindcontrolPanel, 'continuous');
            app.WindAngleKnob.Limits = [-180 180];
            app.SpeedSliderLabel = uilabel(app.WindcontrolPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.WindcontrolPanel);
            app.SpeedSlider.Limits = [0 15];
            app.AutoWind = uicheckbox(app.WindcontrolPanel);
            app.AutoWind.ValueChangedFcn = createCallbackFcn(app, @AutoWindValueChanged, true);
            app.AutoWind.Text = 'Auto';
            app.Base_Reg_ParamPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.Base_Reg_ParamPanel.Title = 'Base Regulator''s Parameters';
            app.StapLimitLabel = uilabel(app.Base_Reg_ParamPanel);
            app.StapLimitLabel.Text = 'Stap Limit, %';
            app.Base_RegStrapLimit = uieditfield(app.Base_Reg_ParamPanel, 'numeric');
            app.Base_RegStrapLimit.Value = 50;
            app.GainSensitivityLabel = uilabel(app.Base_Reg_ParamPanel);
            app.GainSensitivityLabel.Text = 'Gain Sensitivity';
            app.Base_GainSens = uieditfield(app.Base_Reg_ParamPanel, 'numeric');
            app.Base_GainSens.Value = 50;
            app.BoxSizemLabel = uilabel(app.Base_Reg_ParamPanel);
            app.BoxSizemLabel.Text = 'Box Size, m';
            app.BoxSize = uieditfield(app.Base_Reg_ParamPanel, 'numeric');
            app.BoxSize.Value = 0.01;
            app.AngleStepdegLabel = uilabel(app.Base_Reg_ParamPanel);
            app.AngleStepdegLabel.Text = 'AngleStep, deg';
            app.AngleManevr = uieditfield(app.Base_Reg_ParamPanel, 'numeric');
            app.AngleManevr.Value = 30;
            app.ArrivalRadiusmLabel = uilabel(app.Base_Reg_ParamPanel);
            app.ArrivalRadiusmLabel.Text = 'Arrival Radius, m';
            app.ArrivalRadius = uieditfield(app.Base_Reg_ParamPanel, 'numeric');
            app.ArrivalRadius.Value = 200;
            app.RunButton = uibutton(app.BupRegulatorTunerUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.BupRegulatorTunerUIFigure);
            app.dTab = uitab(app.TabGroup);
            app.dTab.Title = '2d';
            app.UIAxes = uiaxes(app.dTab);
            title(app.UIAxes, '2D Trace')
            xlabel(app.UIAxes, 'Lon')
            ylabel(app.UIAxes, 'Lat')
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '3D';
            app.UIAxes2 = uiaxes(app.DTab);
            title(app.UIAxes2, '3D Trace')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PlantParametersPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.PlantParametersPanel.Title = 'Plant Parameters';
            app.HorspeedVhmsEditFieldLabel = uilabel(app.PlantParametersPanel);
            app.HorspeedVhmsEditFieldLabel.Text = 'Hor speed Vh, m/s';
            app.Vh_Edit = uieditfield(app.PlantParametersPanel, 'numeric');
            app.Vh_Edit.Value = 10;
            app.VerspeedVvmsEditFieldLabel = uilabel(app.PlantParametersPanel);
            app.VerspeedVvmsEditFieldLabel.Text = 'Ver speed Vv, m/s';
            app.Vv_Edit = uieditfield(app.PlantParametersPanel, 'numeric');
            app.Vv_Edit.Value = 8.3;
            app.Turnspeedat50degsLabel = uilabel(app.PlantParametersPanel);
            app.Turnspeedat50degsLabel.Text = 'Turn speed at 50%, deg/s';
            app.TurnSpeed_Edit = uieditfield(app.PlantParametersPanel, 'numeric');
            app.TurnSpeed_Edit.Value = 3;
            app.LoadLastParametersButton = uibutton(app.BupRegulatorTunerUIFigure, 'push');
            app.LoadLastParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastParametersButtonPushed, true);
            app.LoadLastParametersButton.Text = 'Load Last Parameters';
            app.Reg_SwitchPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.Reg_SwitchPanel.Title = 'Regulator''s Switch';
            app.TouchdownCommandSwitchLabel = uilabel(app.Reg_SwitchPanel);
            app.TouchdownCommandSwitchLabel.Text = 'Touchdown Command';
            app.TD_CMD_Switch = uiswitch(app.Reg_SwitchPanel, 'slider');
            app.TD_CMD_Switch.ItemsData = {'0', '1'};
            app.TD_CMD_Switch.Value = '0';
            app.TouchdownRegulatorSwitchLabel = uilabel(app.Reg_SwitchPanel);
            app.TouchdownRegulatorSwitchLabel.Enable = 'off';
            app.TouchdownRegulatorSwitchLabel.Text = 'Touchdown Regulator';
            app.TD_Reg_Select = uiswitch(app.Reg_SwitchPanel, 'slider');
            app.TD_Reg_Select.ItemsData = {'0', '1'};
            app.TD_Reg_Select.ValueChangedFcn = createCallbackFcn(app, @TD_Reg_SelectValueChanged, true);
            app.TD_Reg_Select.Enable = 'off';
            app.TD_Reg_Select.Value = '0';
            app.InternalcoursecalculatorSwitchLabel = uilabel(app.Reg_SwitchPanel);
            app.InternalcoursecalculatorSwitchLabel.Text = 'Internal course calculator';
            app.Int_Course_Calc = uiswitch(app.Reg_SwitchPanel, 'slider');
            app.Int_Course_Calc.ItemsData = {'0', '1'};
            app.Int_Course_Calc.Value = '1';
            app.RegulatorMode = uibuttongroup(app.BupRegulatorTunerUIFigure);
            app.RegulatorMode.Title = 'RegulatorMode';
            app.OnlyTDPButton = uiradiobutton(app.RegulatorMode);
            app.OnlyTDPButton.Text = 'Only TDP';
            app.BoxButton = uiradiobutton(app.RegulatorMode);
            app.BoxButton.Text = 'Box';
            app.AngleBoxButton = uiradiobutton(app.RegulatorMode);
            app.AngleBoxButton.Text = 'Angle & Box';
            app.AngleBoxButton.Value = true;
            app.ReliefSettings = uipanel(app.BupRegulatorTunerUIFigure);
            app.ReliefSettings.Title = 'Relief Settings';
            app.AutoRelief = uicheckbox(app.ReliefSettings);
            app.AutoRelief.ValueChangedFcn = createCallbackFcn(app, @AutoReliefValueChanged, true);
            app.AutoRelief.Text = 'Auto';
            app.CurrentpointreliefmLabel = uilabel(app.ReliefSettings);
            app.CurrentpointreliefmLabel.Text = 'Current point relief, m';
            app.ConstRelief_EditField = uieditfield(app.ReliefSettings, 'numeric');
            app.TouchdownpointreliefmLabel = uilabel(app.ReliefSettings);
            app.TouchdownpointreliefmLabel.Text = 'Touchdown point relief, m';
            app.ConstTDPRelief_EditField = uieditfield(app.ReliefSettings, 'numeric');
            app.ConstAvailable = uicheckbox(app.ReliefSettings);
            app.ConstAvailable.Text = 'Const Available';
            app.RadiopointreliefmEditFieldLabel = uilabel(app.ReliefSettings);
            app.RadiopointreliefmEditFieldLabel.Text = 'Radio point relief, m';
            app.RadioPointReliefEdit = uieditfield(app.ReliefSettings, 'numeric');
            app.radioPointPanel = uipanel(app.BupRegulatorTunerUIFigure);
            app.radioPointPanel.Title = 'Radio point';
            app.LatitudedegLabel_3 = uilabel(app.radioPointPanel);
            app.LatitudedegLabel_3.Text = 'Latitude, deg';
            app.radioPointLatitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointLatitudeEdit.Limits = [-90 90];
            app.radioPointLatitudeEdit.ValueDisplayFormat = '%.6f';
            app.radioPointLatitudeEdit.Value = 56.86825;
            app.LongitudedegLabel_3 = uilabel(app.radioPointPanel);
            app.LongitudedegLabel_3.Text = 'Longitude, deg';
            app.radioPointLongitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointLongitudeEdit.ValueDisplayFormat = '%.6f';
            app.radioPointLongitudeEdit.Value = 41.48775;
            app.AltitudemLabel_3 = uilabel(app.radioPointPanel);
            app.AltitudemLabel_3.Text = 'Altitude, m';
            app.radioPointAltitudeEdit = uieditfield(app.radioPointPanel, 'numeric');
            app.radioPointAltitudeEdit.ValueDisplayFormat = '%.3f';
            app.radioPointAltitudeEdit.Value = 16000;
            app.SendtimesecLabel = uilabel(app.radioPointPanel);
            app.SendtimesecLabel.Text = 'Send time, sec';
            app.sendtimeEdit = uieditfield(app.radioPointPanel, 'text');
            app.sendtimeEdit.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ControllerTuner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BupRegulatorTunerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BupRegulatorTunerUIFigure)
        end
    end
end